classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FaceRecognitionSystemLabel  matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        ExitButton                  matlab.ui.control.Button
        RecognizeFaceButton         matlab.ui.control.Button
        ResultBoxPanel              matlab.ui.container.Panel
        ConfidenceLabel             matlab.ui.control.Label
        RecognizedUserLabel         matlab.ui.control.Label
        AccessGrantedFaceRecognizedLabel  matlab.ui.control.Label
        AccessDeniedFaceNotRecognizedLabel  matlab.ui.control.Label
        UploadFaceImageButton       matlab.ui.control.Button
        MetaOptBioLabel             matlab.ui.control.Label
        Image4                      matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadFaceImageButton
        function UploadFaceImageButtonPushed(app, event)
  %----------------------------------------------------------
   [fileName, pathName] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files (*.jpg, *.png, *.jpeg)'});
     % uigetfile : To Open File  , fileName :  To save image name    ,   pathName : image path
	
	
    if isequal(fileName, 0)
        return; 
    end
     % To exit the function if no image was selected 
   
   
    fullPath = fullfile(pathName, fileName);
    rawImage = imread(fullPath);
    % To read the image data and the full path
   
    imshow(rawImage, 'Parent', app.UIAxes);
    app.UIAxes.UserData = rawImage; 
   % To display the image  and store it in Userdata 
   %----------------------------------------------------------

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: RecognizeFaceButton
        function RecognizeFaceButtonPushed(app, event)
  %----------------------------------------------------------

    % 1:
	% To verify that the image has been uploaded, if it hasn't been uploaded, a alert will appear 
    if isempty(app.UIAxes.UserData)
        uialert(app.UIFigure, 'No image has been selected. Please select an image to proceed with recognition. ', 'Missing Input');
        return;   %  Cancel execution if no image is detected
    end
    
	
	% 2:
    % To Load the “FinalModel.mat” ; if it is not found, a warning will be displayed
    if exist('FinalModel.mat', 'file')
        load('FinalModel.mat', 'trainedNet', 'finalModel', 'bestFeatures');
    else
        uialert(app.UIFigure, 'System Error: FinalModel.mat not found.', 'Critical Error');
        return;
    end
    
	
    % 3:
	% Pre-process the image
    testImg = imresize(app.UIAxes.UserData, [224 224]); % Resize for GoogLeNet
    rawFeatures = activations(trainedNet, testImg, 'pool5-7x7_s1', 'OutputAs', 'rows');% Extract deep features
    optimizedFeatures = rawFeatures(:, logical(bestFeatures));% Apply ACO feature selection
    
    % 4:
    % Search for the nearest neighbor , calculate Euclidean distance
    trainedFeatures = finalModel.X; 
    trainedLabels = finalModel.Y;
    
    % To Find the closest match
    [index, actualDist] = knnsearch(trainedFeatures, optimizedFeatures);
    
    % Print distance for debugging , The smaller the number, the greater the similarity.
    fprintf('>>> Final Security Check - Distance: %f \n', actualDist);
    
    % 5: 
	 % Greater than 4.30 = for an unauthorized person; 
	  % less than that = for an authorized person.
    maxAllowedDistance = 4.30; 
    
    % 6:
	 % Final Decision:
    if actualDist < maxAllowedDistance
        % For Access Granted Case :
        app.RecognizedUserLabel.Text = ['User: ', char(trainedLabels(index))];
        app.ConfidenceLabel.Text = sprintf('Dist: %.2f', actualDist);
        app.AccessGrantedFaceRecognizedLabel.Visible = 'on';
        app.AccessDeniedFaceNotRecognizedLabel.Visible = 'off';
        app.ResultBoxPanel.BackgroundColor = [0.8 1 0.8]; % Light Green
    else
        % For Access Denied Case :
        app.RecognizedUserLabel.Text = 'Unknown User';
        app.ConfidenceLabel.Text = sprintf('Dist: %.2f', actualDist);
        app.AccessGrantedFaceRecognizedLabel.Visible = 'off';
        app.AccessDeniedFaceNotRecognizedLabel.Visible = 'on';
        app.ResultBoxPanel.BackgroundColor = [1 0.8 0.8]; % Light Red
    end
     %----------------------------------------------------------
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'pink');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            colormap(app.UIAxes, 'sky')
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-04-10 024036.png');
            app.MetaOptBioLabel = uilabel(app.UIFigure);
            app.MetaOptBioLabel.Text = 'MetaOptBio ';
            app.UploadFaceImageButton = uibutton(app.UIFigure, 'push');
            app.UploadFaceImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFaceImageButtonPushed, true);
            app.UploadFaceImageButton.Text = 'Upload Face Image';
            app.ResultBoxPanel = uipanel(app.UIFigure);
            app.ResultBoxPanel.Title = 'Result Box';
            app.AccessDeniedFaceNotRecognizedLabel = uilabel(app.ResultBoxPanel);
            app.AccessDeniedFaceNotRecognizedLabel.Visible = 'off';
            app.AccessDeniedFaceNotRecognizedLabel.Text = ' Access Denied – Face Not Recognized ';
            app.AccessGrantedFaceRecognizedLabel = uilabel(app.ResultBoxPanel);
            app.AccessGrantedFaceRecognizedLabel.Visible = 'off';
            app.AccessGrantedFaceRecognizedLabel.Text = ' Access Granted – Face Recognized ';
            app.RecognizedUserLabel = uilabel(app.ResultBoxPanel);
            app.RecognizedUserLabel.Text = 'Recognized User: ';
            app.ConfidenceLabel = uilabel(app.ResultBoxPanel);
            app.ConfidenceLabel.Text = 'Confidence: %';
            app.RecognizeFaceButton = uibutton(app.UIFigure, 'push');
            app.RecognizeFaceButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeFaceButtonPushed, true);
            app.RecognizeFaceButton.Text = 'Recognize Face';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Picture1.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-04-10 024036.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-04-10 024036.png');
            app.FaceRecognitionSystemLabel = uilabel(app.UIFigure);
            app.FaceRecognitionSystemLabel.Text = 'Face Recognition System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end